classdef spiral_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        StopButton       matlab.ui.control.Button
        CyclesSpinner    matlab.ui.control.Spinner
        CyclesLabel      matlab.ui.control.Label
        spiralmLabel     matlab.ui.control.Label
        ApplyButton      matlab.ui.control.Button
        rSlider          matlab.ui.control.Slider
        rSliderLabel     matlab.ui.control.Label
        REditField       matlab.ui.control.NumericEditField
        REditFieldLabel  matlab.ui.control.Label
        NDropDown        matlab.ui.control.DropDown
        NDropDownLabel   matlab.ui.control.Label
        UIAxes           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            app.ApplyButton.Enable = false;
            app.StopButton.Enable = true;
            N = app.NDropDown.Value;
            N = str2double(N);
            R = app.REditField.Value;
            r = app.rSlider.Value;
            phi=linspace(0,2*pi,N);
            x = (R+r.*cos(R*phi)).*cos(phi);
            y = (R+r.*cos(R*phi)).*sin(phi);
            z = r.*sin(R*phi);

            x2 = (R+r.*cos(R*phi+pi)).*cos(phi);
            y2 = (R+r.*cos(R*phi+pi)).*sin(phi);
            z2 = r.*sin(R*phi+pi);
            
           
            axis(app.UIAxes,"equal")
            axis(app.UIAxes,"auto")
            hold(app.UIAxes,"on")
            plot3(app.UIAxes,x,y,z,'Color',[0,0,0]) % black spiral
            plot3(app.UIAxes,x2,y2,z2,'Color',[1,0,0]) % red spiral
            bdot = scatter3(app.UIAxes,x(1),y(1),z(1),'black'); % defining the black dot
            rdot = scatter3(app.UIAxes,x2(1),y2(1),z2(1),'red'); % defining the red dot
            
                for cyc = 1:app.CyclesSpinner.Value 
                    for f=1:N
                        bdot.XData = x(f);
                        bdot.YData = y(f);
                        bdot.ZData = z(f);
                        rdot.XData = x2(f);
                        rdot.YData = y2(f);
                        rdot.ZData = z2(f);
                    
                    drawnow
                    pause(0.002)
                    if app.StopButton.Enable == false
                        break
                    end
                    end
                end
                cla(app.UIAxes)
                hold(app.UIAxes,"off")
                app.ApplyButton.Enable = true;
                app.StopButton.Enable = false;
        end

        % Callback function
        function NDropDownValueChanged(app, event)
            
        end

        % Callback function
        function REditFieldValueChanged(app, event)

        end

        % Callback function
        function rSliderValueChanged(app, event)

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
        app.StopButton.Enable = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Graph')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NDropDownLabel = uilabel(app.UIFigure);
            app.NDropDownLabel.Text = 'N';
            app.NDropDown = uidropdown(app.UIFigure);
            app.NDropDown.Items = {'10', '30', '100', '300', '1000', '10000'};
            app.NDropDown.Value = '1000';
            app.REditFieldLabel = uilabel(app.UIFigure);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.UIFigure, 'numeric');
            app.REditField.Value = 12;
            app.rSliderLabel = uilabel(app.UIFigure);
            app.rSliderLabel.Text = 'r';
            app.rSlider = uislider(app.UIFigure);
            app.rSlider.Limits = [0 300];
            app.rSlider.Value = 5;
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.spiralmLabel = uilabel(app.UIFigure);
            app.spiralmLabel.Text = 'spiral.m';
            app.CyclesLabel = uilabel(app.UIFigure);
            app.CyclesLabel.Text = 'Cycles';
            app.CyclesSpinner = uispinner(app.UIFigure);
            app.CyclesSpinner.Limits = [1 4];
            app.CyclesSpinner.Value = 3;
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = spiral_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end